classdef eVision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        PredictButton                 matlab.ui.control.Button
        KeywordsDropDown              matlab.ui.control.DropDown
        KeywordsDropDownLabel         matlab.ui.control.Label
        ofweeksaheadDropDown          matlab.ui.control.DropDown
        ofweeksaheadDropDownLabel     matlab.ui.control.Label
        SublevelDropDown              matlab.ui.control.DropDown
        SublevelDropDownLabel         matlab.ui.control.Label
        LevelDropDown                 matlab.ui.control.DropDown
        LevelDropDownLabel            matlab.ui.control.Label
        PredictionLevelDropDown       matlab.ui.control.DropDown
        PredictionLevelDropDownLabel  matlab.ui.control.Label
        DiseaseDropDown               matlab.ui.control.DropDown
        DiseaseDropDownLabel          matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        AccuracyRateEditField         matlab.ui.control.NumericEditField
        AccuracyRateEditFieldLabel    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function graphData(app, iliRes, iliAct, confidence, error, dates)
            dates = dates(length(dates) - length(iliAct) + 1:length(dates));
            %app.ProgressBar.Title = 'Graphing Results';
            %app.PredictCounter = app.PredictCounter + 1;
            setappdata(0, 'results', iliRes);
            setappdata(0, 'confidence', confidence);
            plot(app.UIAxes, dates, iliRes)
            hold(app.UIAxes, "on");
            plot(app.UIAxes, dates, iliAct, 'g')
            plot(app.UIAxes, dates, iliRes+confidence, 'r--')
            plot(app.UIAxes, dates, iliRes-confidence, 'r--')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'ILI Cases')
            title(app.UIAxes, [app.DiseaseDropDown.Value, ' Data For ', app.SublevelDropDown.Value])
            legend(app.UIAxes, {'predicted', 'actual', 'confidence intervals'}, 'Location', 'northwest')
            hold(app.UIAxes, "off");
            app.AccuracyRateEditField.Value = (1 - error) * 100;
            
%             app.ConfidenceSlider.Visible = 'on';
            
            app.AccuracyRateEditField.Visible = 'on';
            app.AccuracyRateEditFieldLabel.Visible = 'on';
            % plots prediction
            % app.Download.Visible = 'on';
        end

        function graphDataTB(app, YTest, YPred)
            hold(app.UIAxes, "on");
            plot(app.UIAxes, YTest)
            plot(app.UIAxes,YPred)
            xlabel(app.UIAxes,'Weeks')
            ylabel(app.UIAxes,'Z-score of Influenza Positive Viruses')
            legend(app.UIAxes,'actual ', 'predicted')
            hold(app.UIAxes, "off");
        end
        
        function setdata(app, states, codes)
            setappdata(0, 'codes', codes); % stores codes of level
            setappdata(0, 'names', states); % stores names of level
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DiseaseDropDown
        function DiseaseDropDownValueChanged(app, event)
            value = app.DiseaseDropDown.Value;
            national = {'--'}
            if strcmp(value, 'Influenza')     
                app.PredictionLevelDropDown.Visible = 'on';
                app.PredictionLevelDropDownLabel.Visible = 'on';
                app.LevelDropDownLabel.Visible = 'on';
                app.LevelDropDown.Visible = 'on';
                app.ofweeksaheadDropDown.Visible = 'on';
                app.ofweeksaheadDropDownLabel.Visible = 'on';
                national = {'--', 'North America'};
                app.SublevelDropDown.Visible = 'off';
                app.SublevelDropDownLabel.Visible = 'off';
                app.KeywordsDropDown.Visible = 'on';
                app.KeywordsDropDownLabel.Visible = 'on';
            elseif strcmp(value, 'TB')
                app.PredictionLevelDropDown.Visible = 'on';
                app.PredictionLevelDropDownLabel.Visible = 'on';
                app.LevelDropDownLabel.Visible = 'on';
                app.LevelDropDown.Visible = 'on';
                app.ofweeksaheadDropDown.Visible = 'off';
                app.ofweeksaheadDropDownLabel.Visible = 'off';
                national = {'--', 'India'};
                app.SublevelDropDown.Visible = 'off';
                app.SublevelDropDownLabel.Visible = 'off';
                app.KeywordsDropDown.Visible = 'off';
                app.KeywordsDropDownLabel.Visible = 'off';
            end
            app.LevelDropDownLabel.Text = sprintf('%s\n%s\n%s', 'Country');
            app.LevelDropDown.Items = national;
        end

        % Value changed function: PredictionLevelDropDown
        function PredictionLevelDropDownValueChanged(app, event)

            value = app.PredictionLevelDropDown.Value; %value is changed to selected level, which is either 'National', 'State'
            if strcmp(value, 'National') 
                app.SublevelDropDown.Visible = 'off';
                app.SublevelDropDownLabel.Visible = 'off';
            elseif strcmp(value, 'State')  
                 app.SublevelDropDown.Visible = 'on';
                 app.SublevelDropDownLabel.Visible = 'on';
            end
            
        end

        % Value changed function: LevelDropDown
        function LevelDropDownValueChanged(app, event)
            if strcmp(app.LevelDropDown.Value, 'North America')
                 try
                    state_data = readtable('data/state-data.xlsx');
                 catch
                    state_data = readtable('data\state-data.xlsx');
                 end
                 state_names = state_data.Region; %extracts names of the states
                 state_codes = state_data.Code
                 setdata(app, state_names, state_codes)
                 state_names = sort(state_names);
                 app.SublevelDropDownLabel.Text = sprintf('%s\n%s\n%s', 'State');
                 app.SublevelDropDown.Items = state_names; %places states in list to select from
            elseif strcmp(app.LevelDropDown.Value, 'India')
                states = {'--', 'Gujarat', 'Uttar Pradesh', 'Maharastra'};
                app.SublevelDropDownLabel.Text = sprintf('%s\n%s\n%s', 'State');
                app.SublevelDropDown.Items = states; %places states in list to select from
            end
            
        end

        % Value changed function: ofweeksaheadDropDown
        function ofweeksaheadDropDownValueChanged(app, event)

            
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            
            %data = py.google_scraper.fetch_data_from_csv(terms, level, states);
            %time = 'today 5-y';
            %date = py.google_scraper.get_date(char(time));
            %date = cellfun(@char, cell(date), 'UniformOutput', false);
            %data = py.google_scraper.get_data(fulldata);
            %data = cellfun(@char, struct(data), 'UniformOutput', false)';
            predict_ahead = str2double(app.ofweeksaheadDropDown.Value);
            if strcmp(app.DiseaseDropDown.Value, 'Influenza')
                terms = [app.KeywordsDropDown.Value];
                level = app.PredictionLevelDropDown.Value;
                states = app.SublevelDropDown.Value;
                % fulldata = py.google_scraper.fetch_data_from_csv(terms, level, states);
                % dates = py.google_scraper.get_dates(fulldata);
                dates = readtable('dates.csv');
                dates = [dates{:,"date"}];
                % dates = cellfun(@char, TABLE2CELL(dates), 'UniformOutput', false)';
                data = readtable('data.csv');
                data = [data{:,:}];
                writematrix(data, "inf_data.csv");
                [iliRes, iliAct, confidence, error, dates] = influenza_predictor(predict_ahead, data, dates);  
                graphData(app, iliRes, iliAct, confidence, double(error), dates)
            elseif strcmp(app.DiseaseDropDown.Value, 'TB')
                level = app.PredictionLevelDropDown.Value;
                if strcmp(level, 'National')
                    tb = readtable('data/tb_dataset_india.csv');
                elseif strcmp(level, 'State')
                    state = app.SublevelDropDown.Value;
                    if strcmp(state, "Gujarat")
                        tb = readtable('data/tb_dataset_gujarat.csv');
                    elseif strcmp(state, "Maharastra")
                        tb = readtable('data/tb_dataset_maharashtra.csv');
                    elseif strcmp(state, "Uttar Pradesh")
                        tb = readtable('data/tb_dataset_uttar_pradesh.csv');
                    end
                end
                tb_trend  = readtable('data/Interest in TB India.csv')
                %%
                tb(:,"Var1") = []
                tb(:,"Var2") = []
                tb(1,:) = []
                tb_trend(:,"Dates") = [];
                data = [tb{:,:}',tb_trend{:,:}];
                writematrix(data, "tb_data.csv")

                [tbRes, tbAct, err, mapeSum, YTest, YPred] = tb_predictor(data);
                graphDataTB(app, YTest, YPred)
            elseif strcmp(app.DiseaseDropDown.Value, 'COVID-19')
                try
                    data = readtable('covid-data/us.csv');
                catch
                    data = readtable('covid-data\us.csv');
                end
                time = 'today 5-y';
                dates = py.google_scraper.get_date(char(time));
                dates = cellfun(@char, cell(dates), 'UniformOutput', false)';
                [iliRes, iliAct, confidence, error, dates] = covid_predictor(predict_ahead, data, dates);

            end
            %app.AccuracyRateEditField.Value = double(error);
            % graphData(app, iliRes, iliAct, confidence, double(error), dates)
            %close(app.ProgressBar)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {241, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DiseaseDropDownLabel = uilabel(app.LeftPanel);
            app.DiseaseDropDownLabel.Text = 'Disease';
            app.DiseaseDropDown = uidropdown(app.LeftPanel);
            app.DiseaseDropDown.Items = {'--', 'Influenza', 'TB', 'COVID-19'};
            app.DiseaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DiseaseDropDownValueChanged, true);
            app.DiseaseDropDown.Value = '--';
            app.PredictionLevelDropDownLabel = uilabel(app.LeftPanel);
            app.PredictionLevelDropDownLabel.Visible = 'off';
            app.PredictionLevelDropDownLabel.Text = 'Prediction  Level';
            app.PredictionLevelDropDown = uidropdown(app.LeftPanel);
            app.PredictionLevelDropDown.Items = {'--', 'National', 'State'};
            app.PredictionLevelDropDown.ValueChangedFcn = createCallbackFcn(app, @PredictionLevelDropDownValueChanged, true);
            app.PredictionLevelDropDown.Visible = 'off';
            app.PredictionLevelDropDown.Value = '--';
            app.LevelDropDownLabel = uilabel(app.LeftPanel);
            app.LevelDropDownLabel.Visible = 'off';
            app.LevelDropDownLabel.Text = 'Level';
            app.LevelDropDown = uidropdown(app.LeftPanel);
            app.LevelDropDown.Items = {'--', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.LevelDropDown.ValueChangedFcn = createCallbackFcn(app, @LevelDropDownValueChanged, true);
            app.LevelDropDown.Visible = 'off';
            app.LevelDropDown.Value = '--';
            app.SublevelDropDownLabel = uilabel(app.LeftPanel);
            app.SublevelDropDownLabel.Visible = 'off';
            app.SublevelDropDownLabel.Text = 'Sublevel';
            app.SublevelDropDown = uidropdown(app.LeftPanel);
            app.SublevelDropDown.Items = {'--'};
            app.SublevelDropDown.Visible = 'off';
            app.SublevelDropDown.Value = '--';
            app.ofweeksaheadDropDownLabel = uilabel(app.LeftPanel);
            app.ofweeksaheadDropDownLabel.Visible = 'off';
            app.ofweeksaheadDropDownLabel.Text = '# of weeks ahead';
            app.ofweeksaheadDropDown = uidropdown(app.LeftPanel);
            app.ofweeksaheadDropDown.Items = {'--', '3', '7', '14'};
            app.ofweeksaheadDropDown.ValueChangedFcn = createCallbackFcn(app, @ofweeksaheadDropDownValueChanged, true);
            app.ofweeksaheadDropDown.Visible = 'off';
            app.ofweeksaheadDropDown.Value = '--';
            app.KeywordsDropDownLabel = uilabel(app.LeftPanel);
            app.KeywordsDropDownLabel.Visible = 'off';
            app.KeywordsDropDownLabel.Text = 'Keywords';
            app.KeywordsDropDown = uidropdown(app.LeftPanel);
            app.KeywordsDropDown.Items = {'cough', 'flu', 'tamiflu', 'sorethroat'};
            app.KeywordsDropDown.Visible = 'off';
            app.KeywordsDropDown.Value = 'cough';
            app.PredictButton = uibutton(app.LeftPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cepheid.png');
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Epic Lab.png');
            app.Image3 = uiimage(app.LeftPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'scu.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'eVision Forecast')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'ILI cases')
            app.UIAxes.XTick = [0 0.5 1];
            app.AccuracyRateEditFieldLabel = uilabel(app.GridLayout2);
            app.AccuracyRateEditFieldLabel.Text = 'Accuracy Rate';
            app.AccuracyRateEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AccuracyRateEditField.LowerLimitInclusive = 'off';
            app.AccuracyRateEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eVision

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end